classdef paramSweepPar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Knob                           matlab.ui.control.DiscreteKnob
        Gauge_parfor                   matlab.ui.control.SemicircularGauge
        Gauge_parfeval                 matlab.ui.control.SemicircularGauge
        forLabel                       matlab.ui.control.Label
        parforLabel                    matlab.ui.control.Label
        parfevalLabel                  matlab.ui.control.Label
        LastrunEditFieldLabel          matlab.ui.control.Label
        Lastrun_for                    matlab.ui.control.NumericEditField
        Panel_parameters               matlab.ui.container.Panel
        NumberofHeightsEditFieldLabel  matlab.ui.control.Label
        NumHeightsEdit                 matlab.ui.control.NumericEditField
        NumberofCrossSectionsEditFieldLabel  matlab.ui.control.Label
        NumCrossSectionsEdit           matlab.ui.control.NumericEditField
        Gauge_for                      matlab.ui.control.SemicircularGauge
        Lastrun_parfor                 matlab.ui.control.NumericEditField
        Lastrun_parfeval               matlab.ui.control.NumericEditField
        RUNButton                      matlab.ui.control.Button
        History_parfor                 matlab.ui.control.NumericEditField
        History_parfeval               matlab.ui.control.NumericEditField
        HistoryLabel                   matlab.ui.control.Label
        History_for                    matlab.ui.control.NumericEditField
        Panel_codeCompare              matlab.ui.container.Panel
        CompareCodeButton              matlab.ui.control.Button
        forCheckBox                    matlab.ui.control.CheckBox
        parforCheckBox                 matlab.ui.control.CheckBox
        parfevalCheckBox               matlab.ui.control.CheckBox
        parforAfterEachCheckBox        matlab.ui.control.CheckBox
        parpoolSwitchLabel             matlab.ui.control.Label
        parpoolSwitch                  matlab.ui.control.Switch
        secLabel_2                     matlab.ui.control.Label
        secLabel_3                     matlab.ui.control.Label
        secLabel_4                     matlab.ui.control.Label
        secLabel_5                     matlab.ui.control.Label
        secLabel_6                     matlab.ui.control.Label
        secLabel_7                     matlab.ui.control.Label
        UseAfterEachCheckBox           matlab.ui.control.CheckBox
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        options   = ["for", "parfor", "parfeval", "parforAfterEach"];
        functions = ["paramSweepSerial", "paramSweepParallel", "paramSweepParfeval", "paramSweepParallel_plotAfterEach"];
        checked   = [ 0 0 0 0 ];
    end
    
    methods (Access = public)
        
        function changeStatus(app, status)
            if ismember(status, ["On","Off"])
                
                app.RUNButton.Enable = status;
                app.Knob.Enable = status;
                app.UseAfterEachCheckBox.Enable = status;
                
                app.parpoolSwitch.Enable = status;
                app.parpoolSwitchLabel.Enable = status;
                
                app.NumHeightsEdit.Enable = status;
                app.NumberofHeightsEditFieldLabel.Enable = status;
                app.NumCrossSectionsEdit.Enable = status;
                app.NumberofCrossSectionsEditFieldLabel.Enable = status;
                
                
                app.CompareCodeButton.Enable = status;
                app.forCheckBox.Enable = status;
                app.parforCheckBox.Enable = status;
                app.parforAfterEachCheckBox.Enable = status;
                app.parfevalCheckBox.Enable = status;
            else
                error("Unlegal status input");
            end
        end
        
        function clearHistory(app)
            app.Lastrun_for.Value = 0;
            app.Lastrun_parfor.Value = 0;
            app.Lastrun_parfeval.Value = 0;
            app.History_for.Value = 0;
            app.History_parfor.Value = 0;
            app.History_parfeval.Value = 0;
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Truss Deflection';
            if isempty(gcp('nocreate'))
                app.parpoolSwitch.Value = 'Off';
            else
                app.parpoolSwitch.Value = 'On';
            end
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            
            app.changeStatus('Off');
            
            chosen = app.Knob.Value;
            switch(chosen)
                case app.options(1)
                    isParallel=0;
                    fh=str2func(app.functions(1));
                    th=app.options(1);
                    
                case app.options(2)
                    isParallel=1;
                    if app.UseAfterEachCheckBox.Value
                        fh=str2func(app.functions(4));
                    else
                        fh=str2func(app.functions(2));
                    end
                    th=app.options(2);
                    
                case app.options(3)
                    isParallel=1;
                    fh=str2func(app.functions(3));
                    th=app.options(3);
            end
            
            skipIt=0;
            if isParallel
                try
                    p=gcp;
                    if isempty(p)
                        p=parpool;
                        app.parpoolSwitch.Value = 'On';
                    end
                    
                catch MException
                    disp(MException);
                    skipIt=1;
                end
            end
            
            if ~skipIt
                app.RUNButton.Text = "Running";
                lD.a.valLims=[3 30];
                lD.a.range=[.2 .25];
                
                lD.n.valLims=[2 30];
                lD.n.range=[2 22];
                
                lD.h.valLims=[3 30];
                lD.h.range=[.2 .5];
                
                lD.a.staticValue=mean(lD.a.range);
                lD.n.staticValue=mean(lD.n.range);
                
                try
                    L=1;
                    showTruss=0;
                    nVals=lD.n.staticValue;
                    
                    numA=app.NumCrossSectionsEdit.Value;
                    aVals=linspace(lD.a.range(1),lD.a.range(2),numA);
                    
                    numH=app.NumHeightsEdit.Value;
                    hVals=linspace(lD.h.range(1),lD.h.range(2),numH);
                    
                    [~,compTime]=fh(nVals,hVals,aVals,L,showTruss, app.UIAxes);
                    
                    rotate3d(app.UIAxes,'on');
                    app.("History_" + th).Value =  app.("Lastrun_" + th).Value;
                    app.("Lastrun_" + th).Value = compTime;
                    app.("Gauge_" + th).Value = compTime;
                    
                    app.RUNButton.Text = "Run";
                    app.changeStatus('On');
                    
                    
                catch MException
                    disp(MException);
                end
            end
        end

        % Value changed function: NumHeightsEdit
        function NumHeightsEditValueChanged(app, event)
            app.clearHistory;
        end

        % Value changed function: NumCrossSectionsEdit
        function NumCrossSectionsEditValueChanged(app, event)
            app.clearHistory;
        end

        % Value changed function: parpoolSwitch
        function parpoolSwitchValueChanged(app, event)
            value = app.parpoolSwitch.Value;
            switch value
                case 'Off'
                    delete(gcp('nocreate'));
                case 'On'
                    app.changeStatus('Off');
                    app.RUNButton.Text = 'Starting Pool...';
                    app.RUNButton.FontColor = 'r';
                    parpool;
                    app.changeStatus('On');
                    app.RUNButton.Text = "Run";
                    app.RUNButton.FontColor = 'k';
            end
        end

        % Value changed function: forCheckBox
        function forCheckBoxValueChanged(app, event)
            if app.forCheckBox.Value == 1
                if sum(app.checked) == 2
                    app.forCheckBox.Value = 0;
                    app.checked(1) = 0;
                else
                    app.checked(1) = 1;
                end
            else
                app.checked(1) = 0;
            end
        end

        % Value changed function: parforCheckBox
        function parforCheckBoxValueChanged(app, event)
            if app.parforCheckBox.Value == 1
                if sum(app.checked) == 2
                    app.parforCheckBox.Value = 0;
                    app.checked(2) = 0;
                else
                    app.checked(2) = 1;
                end
            else
                app.checked(2) = 0;
            end
        end

        % Value changed function: parfevalCheckBox
        function parfevalCheckBoxValueChanged(app, event)
            if app.parfevalCheckBox.Value == 1
                if sum(app.checked) == 2
                    app.parfevalCheckBox.Value = 0;
                    app.checked(3) = 0;
                else
                    app.checked(3) = 1;
                end
            else
                app.checked(3) = 0;
            end
        end

        % Button pushed function: CompareCodeButton
        function CompareCodeButtonPushed(app, event)
            indCompare = find(app.checked==1);
            if length(indCompare) == 2
                ver1 = char(app.functions(indCompare(1)));
                ver2 = char(app.functions(indCompare(2)));
                visdiff(ver1, ver2);
            elseif length(indCompare) == 1
                ver1 = char(app.functions(indCompare(1)));
                visdiff(ver1,ver1);
            end
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            if value == app.options(2)
                app.UseAfterEachCheckBox.Enable = 'On';
            else
                app.UseAfterEachCheckBox.Enable = 'Off';
            end
        end

        % Value changed function: parforAfterEachCheckBox
        function parforAfterEachCheckBoxValueChanged(app, event)
            if app.parforAfterEachCheckBox.Value == 1
                if sum(app.checked) == 2
                    app.parforAfterEachCheckBox.Value = 0;
                    app.checked(4) = 0;
                else
                    app.checked(4) = 1;
                end
            else
                app.checked(4) = 0;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Knob = uiknob(app.UIFigure, 'discrete');
            app.Knob.Items = {'for', 'parfor', 'parfeval'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Value = 'for';
            app.Gauge_parfor = uigauge(app.UIFigure, 'semicircular');
            app.Gauge_parfor.Limits = [0 15];
            app.Gauge_parfeval = uigauge(app.UIFigure, 'semicircular');
            app.Gauge_parfeval.Limits = [0 15];
            app.forLabel = uilabel(app.UIFigure);
            app.forLabel.Text = 'for';
            app.parforLabel = uilabel(app.UIFigure);
            app.parforLabel.Text = 'parfor';
            app.parfevalLabel = uilabel(app.UIFigure);
            app.parfevalLabel.Text = 'parfeval';
            app.LastrunEditFieldLabel = uilabel(app.UIFigure);
            app.LastrunEditFieldLabel.Text = 'Last run';
            app.Lastrun_for = uieditfield(app.UIFigure, 'numeric');
            app.Lastrun_for.Editable = 'off';
            app.Panel_parameters = uipanel(app.UIFigure);
            app.NumberofHeightsEditFieldLabel = uilabel(app.Panel_parameters);
            app.NumberofHeightsEditFieldLabel.Text = 'Number of Heights';
            app.NumHeightsEdit = uieditfield(app.Panel_parameters, 'numeric');
            app.NumHeightsEdit.ValueDisplayFormat = '%.0f';
            app.NumHeightsEdit.ValueChangedFcn = createCallbackFcn(app, @NumHeightsEditValueChanged, true);
            app.NumHeightsEdit.Value = 10;
            app.NumberofCrossSectionsEditFieldLabel = uilabel(app.Panel_parameters);
            app.NumberofCrossSectionsEditFieldLabel.Text = 'Number of Cross Sections';
            app.NumCrossSectionsEdit = uieditfield(app.Panel_parameters, 'numeric');
            app.NumCrossSectionsEdit.ValueDisplayFormat = '%.0f';
            app.NumCrossSectionsEdit.ValueChangedFcn = createCallbackFcn(app, @NumCrossSectionsEditValueChanged, true);
            app.NumCrossSectionsEdit.Value = 5;
            app.Gauge_for = uigauge(app.UIFigure, 'semicircular');
            app.Gauge_for.Limits = [0 15];
            app.Lastrun_parfor = uieditfield(app.UIFigure, 'numeric');
            app.Lastrun_parfor.Editable = 'off';
            app.Lastrun_parfeval = uieditfield(app.UIFigure, 'numeric');
            app.Lastrun_parfeval.Editable = 'off';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.History_parfor = uieditfield(app.UIFigure, 'numeric');
            app.History_parfor.Editable = 'off';
            app.History_parfeval = uieditfield(app.UIFigure, 'numeric');
            app.History_parfeval.Editable = 'off';
            app.HistoryLabel = uilabel(app.UIFigure);
            app.HistoryLabel.Text = 'History';
            app.History_for = uieditfield(app.UIFigure, 'numeric');
            app.History_for.Editable = 'off';
            app.Panel_codeCompare = uipanel(app.UIFigure);
            app.CompareCodeButton = uibutton(app.Panel_codeCompare, 'push');
            app.CompareCodeButton.ButtonPushedFcn = createCallbackFcn(app, @CompareCodeButtonPushed, true);
            app.CompareCodeButton.Text = {'Compare'; 'Code'};
            app.forCheckBox = uicheckbox(app.Panel_codeCompare);
            app.forCheckBox.ValueChangedFcn = createCallbackFcn(app, @forCheckBoxValueChanged, true);
            app.forCheckBox.Text = 'for';
            app.parforCheckBox = uicheckbox(app.Panel_codeCompare);
            app.parforCheckBox.ValueChangedFcn = createCallbackFcn(app, @parforCheckBoxValueChanged, true);
            app.parforCheckBox.Text = 'parfor';
            app.parfevalCheckBox = uicheckbox(app.Panel_codeCompare);
            app.parfevalCheckBox.ValueChangedFcn = createCallbackFcn(app, @parfevalCheckBoxValueChanged, true);
            app.parfevalCheckBox.Text = 'parfeval';
            app.parforAfterEachCheckBox = uicheckbox(app.Panel_codeCompare);
            app.parforAfterEachCheckBox.ValueChangedFcn = createCallbackFcn(app, @parforAfterEachCheckBoxValueChanged, true);
            app.parforAfterEachCheckBox.Text = 'parfor + AfterEach';
            app.parpoolSwitchLabel = uilabel(app.UIFigure);
            app.parpoolSwitchLabel.Text = 'parpool';
            app.parpoolSwitch = uiswitch(app.UIFigure, 'slider');
            app.parpoolSwitch.ValueChangedFcn = createCallbackFcn(app, @parpoolSwitchValueChanged, true);
            app.secLabel_2 = uilabel(app.UIFigure);
            app.secLabel_2.Text = 'sec';
            app.secLabel_3 = uilabel(app.UIFigure);
            app.secLabel_3.Text = 'sec';
            app.secLabel_4 = uilabel(app.UIFigure);
            app.secLabel_4.Text = 'sec';
            app.secLabel_5 = uilabel(app.UIFigure);
            app.secLabel_5.Text = 'sec';
            app.secLabel_6 = uilabel(app.UIFigure);
            app.secLabel_6.Text = 'sec';
            app.secLabel_7 = uilabel(app.UIFigure);
            app.secLabel_7.Text = 'sec';
            app.UseAfterEachCheckBox = uicheckbox(app.UIFigure);
            app.UseAfterEachCheckBox.Enable = 'off';
            app.UseAfterEachCheckBox.Text = 'Use AfterEach (parfor only)';
            app.UIAxes = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = paramSweepPar

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end